classdef WidefieldAdapted < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ManualAcquisitionControlPanel  matlab.ui.container.Panel
        triggerLock                    matlab.ui.control.Button
        StopTrigger                    matlab.ui.control.Button
        StimTrigger                    matlab.ui.control.Button
        TrialTrigger                   matlab.ui.control.Button
        RecordingStatusPanel           matlab.ui.container.Panel
        lockGUI                        matlab.ui.control.StateButton
        CurrentStatus                  matlab.ui.control.StateButton
        AcquisitionStatus              matlab.ui.control.StateButton
        WaitForTrigger                 matlab.ui.control.StateButton
        DataStoragePanel               matlab.ui.container.Panel
        WaitingTime                    matlab.ui.control.NumericEditField
        PostStimLabel_2                matlab.ui.control.Label
        PostStimFrames                 matlab.ui.control.NumericEditField
        PostStimLabel                  matlab.ui.control.Label
        BaselineFrames                 matlab.ui.control.NumericEditField
        BaselinesLabel                 matlab.ui.control.Label
        DriveSelect                    matlab.ui.control.DropDown
        DriveSelectDropDownLabel       matlab.ui.control.Label
        BinningDropDown                matlab.ui.control.DropDown
        BinningDropDownLabel           matlab.ui.control.Label
        DataPath                       matlab.ui.control.TextArea
        FilePathTextAreaLabel          matlab.ui.control.Label
        PreviousTrialsListBox          matlab.ui.control.ListBox
        LoadPreviousButton             matlab.ui.control.Button
        ControlsPanel                  matlab.ui.container.Panel
        CurrentResolution              matlab.ui.control.EditField
        ResolutionEditFieldLabel       matlab.ui.control.Label
        TakeSnapshotButton             matlab.ui.control.Button
        GaindBSpinner                  matlab.ui.control.Spinner
        GaindBSpinnerLabel             matlab.ui.control.Label
        ResetROIButton                 matlab.ui.control.Button
        SelectROIButton                matlab.ui.control.Button
        StartPreviewButton             matlab.ui.control.StateButton
        ExposureTimeEditField          matlab.ui.control.NumericEditField
        ExposureTimeEditFieldLabel     matlab.ui.control.Label
        FrameRate                      matlab.ui.control.NumericEditField
        FramerateHzEditFieldLabel      matlab.ui.control.Label
        LightSync                      matlab.ui.control.DropDown
        LightSyncDropDownLabel         matlab.ui.control.Label
        LightMode                      matlab.ui.control.DropDown
        LightModeDropDownLabel         matlab.ui.control.Label
        ExperimentInformationPanel     matlab.ui.container.Panel
        RecordModeLabel                matlab.ui.control.Label
        TrialNr                        matlab.ui.control.Label
        RecordingID                    matlab.ui.control.Label
        TrialNrLabel                   matlab.ui.control.Label
        RecordingIDLabel               matlab.ui.control.Label
        RecordMode                     matlab.ui.control.DropDown
        NewExperimentButton            matlab.ui.control.Button
        ExperimentType                 matlab.ui.control.DropDown
        ExperimentDropDownLabel        matlab.ui.control.Label
        NewAnimalButton                matlab.ui.control.Button
        AnimalID                       matlab.ui.control.DropDown
        AnimalIDDropDownLabel          matlab.ui.control.Label
        FrameSlider                    matlab.ui.control.Slider
        FluorescenceTraceAxes          matlab.ui.control.UIAxes
        ImagePlot                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        daqName % name of the national instruments DAQ board
        extraFrames % amount of frames where the light is switched off at the end of the recording. This helps to ensure proper alignment with analog data.
        minSize % minimum free disk space before producing a warning (in GB)
        serverPath % data server path
        aNIdevice % analog handle for daq device
        dNIdevice % digital handle for daq device
        vidName % handle for video name
        vidObj % handle for video device
        calibration % Placeholder for calibration mode TODO DO I NEED THIS

        % Path variables - adapted from handles.path which had attributes base, AnimalID, ExpType, RecordingID, 
        pathBase
        pathExpType
        pathAnimalID
        pathRecordingID

        SnapshotTaken % flag to say whether initialisation snapshot has been taken. Recording won't start if it's false
    end
    
    methods (Access = private)
        
        function vidObj = CheckCamera(app)
            % check videoadapter, set settings from the GUI and start video preview. 
            % Returns an empty object if 'adaptorname' is not available.
            % TODO MAKE THIS CONFIGURABLE
            vidObj = [];
            adaptorName = app.vidName;
            try
                imaqreset
                vidObj = videoinput(adaptorName); %get video object
                src = getselectedsource(vidObj);
                
                if contains(adaptorName, 'pcocameraadaptor') %check for PCO camera and set specific settings
                    clockSpeed = set(src,'PCPixelclock_Hz');
                    [~,idx] = max(str2num(cell2mat(clockSpeed))); %get fastest clockspeed
                    src.PCPixelclock_Hz = clockSpeed{idx}; %fast scanning mode
                    src.E2ExposureTime = 1000/str2double(app.FrameRate.Value) * 1000; %set framerate
                    binFact = 4; %set 4x binning by default
                    
                    try %uses different string in different adaptor versions...
                        src.B1BinningHorizontal = num2str(binFact);
                        src.B2BinningVertical = num2str(binFact);
                    catch
                        src.B1BinningHorizontal = num2str(binFact,'%02i');
                        src.B2BinningVertical = num2str(binFact,'%02i');
                    end
                else
                    try
                        app.FrameRate.Style = 'popupmenu'; %change menu style to indicate available frame rates
                        app.FrameRate.Value = set(src, 'FrameRate');
                        src.FrameRate = app.FrameRate.Value{1}; %use highest available frame rate
                    catch
                        app.FrameRate.Value = {'30'};
                    end
                end
                
                %setup and display live video feed in preview window
                vidRes = get(vidObj,'VideoResolution');
                nbands = get(vidObj,'NumberOfBands');
                app.CurrentResolution.Value = [num2str(vidRes(1)) ' x ' num2str(vidRes(2))]; %update current resolution indicator
                %     imshow(zeros(vidRes(2),vidRes(1),nbands),[],'parent',handles.ImagePlot,'XData',[0 1],'YData',[0 1]); %create image object for preview
                imshow(zeros(vidRes(2),vidRes(1),nbands),[],'parent',app.ImagePlot); %create image object for preview
                
                %set default camera configuration
                app.ROIposition = [0 0 vidRes];  %default ROIposition
                set(vidObj,'TriggerFrameDelay',0);
                set(vidObj,'FrameGrabInterval',1);
                set(vidObj,'TriggerRepeat',0);
                set(vidObj,'ROIposition',app.ROIposition);
                set(vidObj,'FramesPerTrigger',Inf);
                triggerconfig(vidObj,'manual');
                
            catch
                vidObj = [];
            end
                        
        end
        
        function app = CheckPath(app)

            % Look for single-letter drives, starting at a: or c: as appropriate
            try
                if ispc
                ret = {};
                for i = double('c') : double('z')
                    if exist(['' i ':' filesep], 'dir') == 7
                        ret{end+1} = [i ':']; %#ok<AGROW>
                    end
                end
            
                elseif ismac
                   ret = dir('/Volumes/');
                   ret = {ret(3:end).name};
                end
                app.DriveSelect.Value = char(ret);
            end
            
            %TODO a lot of the string nonsense here might be unnecessary
            %with the new way app designer handles dropdown values (string
            %& value are the same now)
            cPath = java.io.File(strtrim(app.DriveSelect.Value(app.DriveSelect.ValueIndex,:))); %make sure this is OS independent
            if (cPath.getFreeSpace / 2^30) < app.minSize && length(app.DriveSelect.Value) > 1 && cPath.getFreeSpace > 0
                answer = questdlg(['Only ' num2str((cPath.getFreeSpace / 2^30)) 'gb left on ' strtrim(app.DriveSelect.Value(app.DriveSelect.ValueIndex,:)) filesep '. Change drive?'], ...
                    'Drive select', 'Yes', 'No', 'No, stop asking me', 'Yes');
                if strcmp(answer, 'Yes')
                    checker = true;
                    cDrive = app.DriveSelect.ValueIndex; %keep current drive index
                    while checker
                        app.DriveSelect.ValueIndex = rem(app.DriveSelect.ValueIndex,length(app.DriveSelect.Value))+1; %increase drive selection value by 1
                        cPath = java.io.File(strtrim(app.DriveSelect.Value(app.DriveSelect.ValueIndex,:)));
                        if (cPath.getFreeSpace / 2^30) > app.minSize
                            disp(['Changed path to drive ' strtrim(app.DriveSelect.Value(app.DriveSelect.ValueIndex,:)) filesep '. ' num2str((cPath.getFreeSpace / 2^30)) 'gb remaining.'])
                            checker = false;
                        elseif app.DriveSelect.ValueIndex == cDrive
                            disp(['Could not find a drive with more then ' num2str(app.minSize) 'gb of free space. Path unchanged.'])
                            checker = false;
                        end
                    end
                elseif strcmp(answer, 'No, stop asking me')
                    app.minSize = 0; %don't check disk size anymore
                end
            end
            
            % set basepath and look for present animals, experiment types and past recordings
            app.pathBase = [char(cPath) filesep strtrim(app.LightSync.Value) filesep]; %set path of imaging code
            
            if ~exist([app.pathBase 'Animals'],'dir') %check for animal path to save data
                mkdir([app.pathBase 'Animals']) %create folder if required
            end

            app.AnimalID.Value = cellstr(app.AnimalID.Value);
            folders = dir([app.pathBase 'Animals']); %find animal folders
            folders = folders([folders.isdir] & ~strncmpi('.', {folders.name}, 1));
            checker = true;
            for iAnimals = 1:size(folders,1) %skip first two entries because they contain folders '.' and '..'
                AllAnimals{iAnimals} = folders(iAnimals).name; %get animal folders
                if checker
                    if strcmp(app.AnimalID.Value,folders(iAnimals).name) %check if current selected animal coincides with discovered folder
                        app.AnimalID.ValueIndex = iAnimals; %keep animal selection constant
                        checker = false;
                    end
                end
            end
            
            if isempty(iAnimals) %Check if any animals are found
                AllAnimals{1} = 'Dummy Subject'; %create dummy animal if nothing else is found
                mkdir([app.pathBase 'Animals' filesep 'Dummy Subject']) %create folder for default experiment
            end
            
            app.AnimalID.Items = AllAnimals; %update AnimalID selection
            if app.AnimalID.ValueIndex > length(AllAnimals)
                app.AnimalID.ValueIndex = 1; %reset indicator
            end
            
            if ~isempty(app.AnimalID.ValueIndex)
                app.pathAnimalID = AllAnimals{app.AnimalID.ValueIndex}; %update path for current animal
            end
            
            folders = dir([app.pathBase 'Animals' filesep AllAnimals{app.AnimalID.ValueIndex}]); %find Experiment folders
            folders = folders([folders.isdir] & ~strncmpi('.', {folders.name}, 1));
            for iExperiments = 1:size(folders,1) %skip first two entries because they contain folders '.' and '..'
                AllExperiments{iExperiments} = folders(iExperiments).name; %get experiment folders
            end
            if isempty(iExperiments) %Check if any experiments are found
                AllExperiments{1} = 'Default'; %create default experiment if nothing else is found
                mkdir([app.pathBase 'Animals' filesep AllAnimals{1} filesep 'Default']) %create folder for default experiment
            end

            app.ExperimentType.Items = AllExperiments; %update experiment type selection
            if size(AllExperiments,2) < app.ExperimentType.ValueIndex 
                app.ExperimentType.ValueIndex = 1; 
            end
            app.pathExpType = AllExperiments{app.ExperimentType.ValueIndex}; %update path for current experiment type
            cPath = [app.pathBase 'Animals' filesep AllAnimals{app.AnimalID.ValueIndex} filesep AllExperiments{app.ExperimentType.ValueIndex}]; %assign current path
            
            date = string(datetime('today'));
            if exist(strcat(cPath, filesep, date), 'dir') && length(dir(strcat(cPath, filesep, date))) > 2 %check if folder for current date exist already and contains data
                Cnt = 1;
                while exist(strcat(cPath, filesep, date, '_', num2str(Cnt)),'dir')
                    Cnt = Cnt +1; %update counter until it is ensured that current experiment name is not used already
                end
                app.pathRecordingID = strcat(date, '_', num2str(Cnt)); %set folder for recording day as the date + neccesarry counter
            else
                app.pathRecordingID = date; %set folder for current recording to recording date
            end
            app.RecordingID.Text = app.pathRecordingID; %update GUI
            app.DataPath.Value = strcat(cPath, filesep, app.pathRecordingID); %set complete file path for data storage
            set(app.TrialNr,'Text','0'); %reset TrialNr
            app.SnapshotTaken = false; %flag for snapshot - has to be taken in order to start data acquisition
            app.CurrentStatus.Text = 'Not ready'; %reset status indicator
            guidata(app.WidefieldImager, app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.daqName = "Dev1";
            app.extraFrames = 5;
            app.minSize = 100;
            app.serverPath = '\\your server path';
            
            app.LightSync.Value;
            % Initialise NI card
            LightSyncValueChanged(app, []);
            % guidata(handles.WidefieldImager, handles) TODO put this in if needed
            %% initialize camera and set handles
            info = imaqhwinfo;
            app.vidName = [];
            camCheck = contains(info.InstalledAdaptors, 'pcocameraadaptor');
            
            if any(camCheck)
                app.vidName = info.InstalledAdaptors{camCheck}; %PCO camera
            end
            app.vidObj = CheckCamera(app); %get PCO video object %TODO checkCamera
            
            % check for other cameras if PCO adaptor is unavailable.
            if isempty(app.vidObj)
                disp('PCO camera not available. Searching for other cameras.');
                app.vidName = [];
                if length(info.InstalledAdaptors) == 1
                    app.vidName = info.InstalledAdaptors{1};
                elseif length(info.InstalledAdaptors) > 1 %multiple adaptors present, ask for selection
                    out = listdlg('Name', 'Please select your camera', ...
                        'SelectionMode','single','liststring',info.InstalledAdaptors,'listsize',[300 300]);
                    if ~isempty(out)
                        app.vidName = info.InstalledAdaptors{out};
                    end
                end
                app.BinningDropDown.Enable = 'off'; %this only works with PCO camera so disable for other cameras
            end
            
            if ~isempty(app.vidName)
                fprintf('Using %s as current video adapter\n',app.vidName);
                app.vidObj = CheckCamera(app); %get non-PCO video object
                
                if ~isempty(app.vidObj)
                    preview(app.vidObj,app.ImagePlot.Children); %start preview %TODO preview
                    maxRange = floor(256*0.7); %limit intensity to 70% of dynamic range to avoid ceiling effects
                    cMap = gray(maxRange); cMap(end+1:256,:) = repmat([1 0 0 ],256-maxRange,1);
                    colormap(app.ImagePlot,cMap);
                end
            end
            
            if isempty(app.vidObj)
                warning('No camera found. Type "imaqhwinfo" to make sure your camera is properly installed and running.')
            end

            if any(ismember(app.DriveSelect.Value(:,1), 'g')) %start on G: drive by default
                app.DriveSelect.Value = find(ismember(app.DriveSelect.Value(:,1), 'g'));
            end
            
            % set timer for calibration mode
            app.calibration = [];
            CheckPath(app); %Check for data path, reset date and trialcount
            
            % UIWAIT makes WidefieldImager wait for user response (see UIRESUME)
            % uiwait(handles.WidefieldImager);
        end

        % Value changed function: LightSync
        function LightSyncValueChanged(app, event)
            value = app.LightSync.Value;
            % TODO FIGURE OUT WHAT THIS DOES
            % handles.ExperimentType.Value = 1;
            % handles.AnimalID.Value = 1;
            % 
            % if hObject.Value == 1 %set standard settings for widefield mapping
            %     handles.BaselineFrames.String = '2';
            %     handles.PostStimFrames.String = '23';
            % elseif  hObject.Value == 2 %set standard settings for behavioral recording
            %     handles.BaselineFrames.String = '3';
            %     handles.PostStimFrames.String = '6';
            % end
            % handles = CheckPath(handles);

            % check NI card
            try
                daqs = daqlist();
            catch
                daqs = [];
            end

            if isempty(daqs)
                disp('No NI devices found or data acquisition toolbox missing.')
                disp('Use software triggers instead.')
                app.dNIdevice = [];
            else
                if isfield(app, 'dNIdevice')
                    delete(app.dNIdevice);
                    delete(app.aNIdevice);
                end

                checker = false; %check if daq device with correct ID is present. Default is 'Dev1'
                for x = 1 : length(daqs)
                    if strcmpi(daqs(x).ID,app.daqName)
                        checker = true;            
                    end
                end
                if ~checker
                    warning(['Could not find specified DAQ: ' app.daqName ' - Using existing board ' daqs(x).ID ' instead.'])
                    app.daqName = daqs(x).ID;
                end

                %Initialise DAQ with correct settings. TODO make sure this is accurate
                app.dNIdevice = daq(daqs(x).Vendor.ID); %object for communication with DAQ - digital lines
                app.aNIdevice = daq(daqs(x).Vendor.ID); %object for communication with DAQ - analog lines
                app.aNIdevice.IsContinuous = true; %set to continous acquisition
                app.aNIdevice.Rate = 1000; %set sampling rate to 1kHz
                
                addDigitalChannel(app.dNIdevice,app.daqName,'port1/line0:2','OutputOnly'); %output channels for blue, violet and mixed light (1.0:blue, 1.1:violet, 1.2:mixed)
                outputSingleScan(app.dNIdevice,false(1,3)); %make sure outputs are false
                addDigitalChannel(app.dNIdevice,app.daqName,'port0/line0:2','InputOnly'); %input channels to trigger data acquisition and control timing of animal behavior (0.2: trial start, 0.3: stim on)

                ch = addAnalogInputChannel(app.aNIdevice,app.daqName, 0:3, 'Voltage');
                for x = 1 : length(ch)
                    ch(x).TerminalConfig = 'SingleEnded'; %use single-ended recordings. Use differential when recording low SNR signals (needs to line for +/-)
                end 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImagePlot = uiaxes(app.UIFigure);
            zlabel(app.ImagePlot, 'Z')
            app.ImagePlot.XTick = [];
            app.ImagePlot.YTick = [];
            app.FluorescenceTraceAxes = uiaxes(app.UIFigure);
            title(app.FluorescenceTraceAxes, 'ROI Fluorescence')
            xlabel(app.FluorescenceTraceAxes, 'X')
            ylabel(app.FluorescenceTraceAxes, 'Y')
            zlabel(app.FluorescenceTraceAxes, 'Z')
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.MajorTicks = [];
            app.ExperimentInformationPanel = uipanel(app.UIFigure);
            app.ExperimentInformationPanel.Title = 'Experiment Information';
            app.AnimalIDDropDownLabel = uilabel(app.ExperimentInformationPanel);
            app.AnimalIDDropDownLabel.Text = 'Animal ID';
            app.AnimalID = uidropdown(app.ExperimentInformationPanel);
            app.AnimalID.Items = {'AnimalNum'};
            app.AnimalID.Value = 'AnimalNum';
            app.NewAnimalButton = uibutton(app.ExperimentInformationPanel, 'push');
            app.NewAnimalButton.Text = 'New Animal';
            app.ExperimentDropDownLabel = uilabel(app.ExperimentInformationPanel);
            app.ExperimentDropDownLabel.Text = 'Experiment';
            app.ExperimentType = uidropdown(app.ExperimentInformationPanel);
            app.ExperimentType.Items = {'ExperimentNum'};
            app.ExperimentType.Value = 'ExperimentNum';
            app.NewExperimentButton = uibutton(app.ExperimentInformationPanel, 'push');
            app.NewExperimentButton.Text = 'New Experiment';
            app.RecordMode = uidropdown(app.ExperimentInformationPanel);
            app.RecordMode.Items = {'Mapping', 'Behavioural'};
            app.RecordMode.Value = 'Mapping';
            app.RecordingIDLabel = uilabel(app.ExperimentInformationPanel);
            app.RecordingIDLabel.Text = 'RecordingID: ';
            app.TrialNrLabel = uilabel(app.ExperimentInformationPanel);
            app.TrialNrLabel.Text = 'TrialNr:';
            app.RecordingID = uilabel(app.ExperimentInformationPanel);
            app.RecordingID.Text = '[None]';
            app.TrialNr = uilabel(app.ExperimentInformationPanel);
            app.TrialNr.Text = '[None]';
            app.RecordModeLabel = uilabel(app.ExperimentInformationPanel);
            app.RecordModeLabel.Text = 'Record Mode';
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.LightModeDropDownLabel = uilabel(app.ControlsPanel);
            app.LightModeDropDownLabel.Text = 'Light Mode';
            app.LightMode = uidropdown(app.ControlsPanel);
            app.LightMode.Items = {'Blue Only', 'Violet Only', 'Alternating'};
            app.LightMode.Value = 'Blue Only';
            app.LightSyncDropDownLabel = uilabel(app.ControlsPanel);
            app.LightSyncDropDownLabel.Text = 'Light Sync';
            app.LightSync = uidropdown(app.ControlsPanel);
            app.LightSync.Items = {'Computer Triggered', 'Camera Triggered'};
            app.LightSync.ValueChangedFcn = createCallbackFcn(app, @LightSyncValueChanged, true);
            app.LightSync.Value = 'Computer Triggered';
            app.FramerateHzEditFieldLabel = uilabel(app.ControlsPanel);
            app.FramerateHzEditFieldLabel.Text = 'Framerate (Hz)';
            app.FrameRate = uieditfield(app.ControlsPanel, 'numeric');
            app.ExposureTimeEditFieldLabel = uilabel(app.ControlsPanel);
            app.ExposureTimeEditFieldLabel.Text = 'ExposureTime ';
            app.ExposureTimeEditField = uieditfield(app.ControlsPanel, 'numeric');
            app.ExposureTimeEditField.Limits = [0 Inf];
            app.StartPreviewButton = uibutton(app.ControlsPanel, 'state');
            app.StartPreviewButton.Text = {'Start'; 'Preview'};
            app.SelectROIButton = uibutton(app.ControlsPanel, 'push');
            app.SelectROIButton.Text = {'Select'; 'ROI'};
            app.ResetROIButton = uibutton(app.ControlsPanel, 'push');
            app.ResetROIButton.Text = {'Reset'; 'ROI'};
            app.GaindBSpinnerLabel = uilabel(app.ControlsPanel);
            app.GaindBSpinnerLabel.Text = 'Gain(dB)';
            app.GaindBSpinner = uispinner(app.ControlsPanel);
            app.GaindBSpinner.Limits = [0 Inf];
            app.TakeSnapshotButton = uibutton(app.ControlsPanel, 'push');
            app.TakeSnapshotButton.Text = {'Take'; 'Snapshot'};
            app.ResolutionEditFieldLabel = uilabel(app.ControlsPanel);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.CurrentResolution = uieditfield(app.ControlsPanel, 'text');
            app.DataStoragePanel = uipanel(app.UIFigure);
            app.DataStoragePanel.Title = 'Data Storage';
            app.LoadPreviousButton = uibutton(app.DataStoragePanel, 'push');
            app.LoadPreviousButton.Text = 'Load Previous';
            app.PreviousTrialsListBox = uilistbox(app.DataStoragePanel);
            app.FilePathTextAreaLabel = uilabel(app.DataStoragePanel);
            app.FilePathTextAreaLabel.Text = 'FilePath';
            app.DataPath = uitextarea(app.DataStoragePanel);
            app.BinningDropDownLabel = uilabel(app.DataStoragePanel);
            app.BinningDropDownLabel.Text = 'Binning';
            app.BinningDropDown = uidropdown(app.DataStoragePanel);
            app.BinningDropDown.Items = {'1', '2', '4'};
            app.BinningDropDown.Value = '1';
            app.DriveSelectDropDownLabel = uilabel(app.DataStoragePanel);
            app.DriveSelectDropDownLabel.Text = 'Drive Select';
            app.DriveSelect = uidropdown(app.DataStoragePanel);
            app.BaselinesLabel = uilabel(app.DataStoragePanel);
            app.BaselinesLabel.Text = 'Baseline (s)';
            app.BaselineFrames = uieditfield(app.DataStoragePanel, 'numeric');
            app.BaselineFrames.Limits = [0 Inf];
            app.BaselineFrames.Value = 2;
            app.PostStimLabel = uilabel(app.DataStoragePanel);
            app.PostStimLabel.Text = 'PostStim (s)';
            app.PostStimFrames = uieditfield(app.DataStoragePanel, 'numeric');
            app.PostStimFrames.Limits = [0 Inf];
            app.PostStimLabel_2 = uilabel(app.DataStoragePanel);
            app.PostStimLabel_2.Text = 'Wait (s)';
            app.WaitingTime = uieditfield(app.DataStoragePanel, 'numeric');
            app.WaitingTime.Value = 60;
            app.RecordingStatusPanel = uipanel(app.UIFigure);
            app.RecordingStatusPanel.Title = 'Recording Status';
            app.WaitForTrigger = uibutton(app.RecordingStatusPanel, 'state');
            app.WaitForTrigger.Text = 'WaitForTrigger';
            app.AcquisitionStatus = uibutton(app.RecordingStatusPanel, 'state');
            app.AcquisitionStatus.Text = 'AcquisitionStatus';
            app.CurrentStatus = uibutton(app.RecordingStatusPanel, 'state');
            app.CurrentStatus.Text = 'CurrentStatus';
            app.lockGUI = uibutton(app.RecordingStatusPanel, 'state');
            app.lockGUI.Text = 'lockGUI';
            app.ManualAcquisitionControlPanel = uipanel(app.UIFigure);
            app.ManualAcquisitionControlPanel.Title = 'Manual Acquisition Control';
            app.TrialTrigger = uibutton(app.ManualAcquisitionControlPanel, 'push');
            app.TrialTrigger.Text = 'TrialTrigger';
            app.StimTrigger = uibutton(app.ManualAcquisitionControlPanel, 'push');
            app.StimTrigger.Text = 'StimTrigger';
            app.StopTrigger = uibutton(app.ManualAcquisitionControlPanel, 'push');
            app.StopTrigger.Text = 'StopTrigger';
            app.triggerLock = uibutton(app.ManualAcquisitionControlPanel, 'push');
            app.triggerLock.Text = 'triggerLock';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WidefieldAdapted

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end