classdef WidefieldImager_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WidefieldImager        matlab.ui.Figure
        slider4                matlab.ui.control.Slider
        CalibrationMode        matlab.ui.control.StateButton
        uipanel6               matlab.ui.container.Panel
        triggerLock            matlab.ui.control.StateButton
        StopTrigger            matlab.ui.control.StateButton
        StimTrigger            matlab.ui.control.StateButton
        TrialTrigger           matlab.ui.control.StateButton
        StatusPanel            matlab.ui.container.Panel
        lockGUI                matlab.ui.control.StateButton
        AcqusitionStatus       matlab.ui.control.StateButton
        CurrentStatus          matlab.ui.control.Button
        WaitForTrigger         matlab.ui.control.StateButton
        DataStorage            matlab.ui.container.Panel
        text16                 matlab.ui.control.Label
        driveSelect            matlab.ui.control.DropDown
        sBinning               matlab.ui.control.DropDown
        Tag_sBinning           matlab.ui.control.Label
        FrameRate              matlab.ui.control.EditField
        Tag_FrameRate          matlab.ui.control.Label
        WaitingTime            matlab.ui.control.EditField
        PostStimFrames         matlab.ui.control.EditField
        BaselineFrames         matlab.ui.control.EditField
        Tag_WaitingTime        matlab.ui.control.Label
        ChangeDataPath         matlab.ui.control.Button
        Tag_BaselineFrames     matlab.ui.control.Label
        Tag_PostStimFrames     matlab.ui.control.Label
        DataPath               matlab.ui.control.Label
        ExperimentID           matlab.ui.container.Panel
        RecordMode             matlab.ui.control.DropDown
        NewExperiment          matlab.ui.control.Button
        NewAnimal              matlab.ui.control.Button
        Tag_TrialNr            matlab.ui.control.Label
        TrialNr                matlab.ui.control.EditField
        Tag_ExperimentType     matlab.ui.control.Label
        ExperimentType         matlab.ui.control.DropDown
        AnimalID               matlab.ui.control.DropDown
        Tag_RecordingID        matlab.ui.control.Label
        Tag_AnimalID           matlab.ui.control.Label
        RecordingID            matlab.ui.control.EditField
        ControlPanel           matlab.ui.container.Panel
        text21                 matlab.ui.control.Label
        text19                 matlab.ui.control.Label
        edit16                 matlab.ui.control.EditField
        edit14                 matlab.ui.control.EditField
        text18                 matlab.ui.control.Label
        text17                 matlab.ui.control.Label
        popupmenu9             matlab.ui.control.DropDown
        lightMode              matlab.ui.control.DropDown
        CurrentResolution      matlab.ui.control.EditField
        Tag_CurrentResolution  matlab.ui.control.Label
        ResetROI               matlab.ui.control.Button
        SelectROI              matlab.ui.control.Button
        StopPreview            matlab.ui.control.Button
        StartPreview           matlab.ui.control.Button
        TakeSnapshot           matlab.ui.control.Button
        BlueLight              matlab.ui.control.StateButton
        axes3                  matlab.ui.control.UIAxes
        ImagePlot              matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function AcquireData(app, hObject)
            % function to acquire images from the camera when GUI is set to wait for
            % trigger and a snapshot has been acquired
            handles = guidata(hObject); %get handles
            
            %% check for errors in trigger lines
            data = false(1,3);
            if ~isempty(handles.dNIdevice)
                data = inputSingleScan(handles.dNIdevice); %trigger lines
            end
            if sum(data) == length(data) %all triggers are active - leave acqusition mode and reset indicators
                disp('Acquire and stimulus triggers are both high - check if triggers are correctly connected and try again.')
                set(handles.WaitForTrigger, 'value' , false)
                set(handles.WaitForTrigger, 'String' , 'Wait for Trigger OFF')
                set(handles.WaitForTrigger, 'BackgroundColor' , '[1 0 0]')
                set(handles.AcqusitionStatus, 'value' , false)
                set(handles.AcqusitionStatus, 'String' , 'Inactive')
                set(handles.AcqusitionStatus, 'BackgroundColor' , '[1 0 0]')
                set(handles.AcqusitionStatus, 'String' , 'Inactive')
                handles.CurrentStatus.String = 'Snapshot taken';
                return
            else
                %% ensure calibration mode is off
                handles.CalibrationMode.Value = false;
                CalibrationMode_Callback(handles.CalibrationMode, [], handles); drawnow;
            
                src = getselectedsource(handles.vidObj);
                if strcmpi(handles.vidName,'pcovideoadapter')
                    src.E2ExposureTime = 1000/str2double(handles.FrameRate.String) * 1000; %make sure current framerate is used
                    if str2double(handles.FrameRate.String) > 10 && strcmp(handles.sBinning.String(handles.sBinning.Value),'1')
                        warning('FrameRate is above 10Hz at full resolution. This can lead to performance issues.')
                    end
                end
                handles.BlueLight.Value = false; BlueLight_Callback(handles.BlueLight, [], handles) %switch LED off
                colormap(handles.ImagePlot,'jet');
            
                %%move saved files to actual data save location
                sFiles = dir([handles.path.base '*Snapshot*']);
                rFiles = dir([handles.path.base '*ROI*']);
                aFiles = [{sFiles.name} {rFiles.name}];
            
                if ~exist(get(handles.DataPath,'String'), 'dir') %create data path if not existent
                    mkdir(get(handles.DataPath,'String'))
                end
                for iFiles = 1:length(aFiles)
                    movefile([handles.path.base aFiles{iFiles}],[get(handles.DataPath,'String') filesep aFiles{iFiles}]); %move files
                end
            
                 %save recorder handles to know all the settings later. Need to disable some warnings temporarily to avoid confusion.
                warning('off','MATLAB:Figure:FigureSavedToMATFile');
                warning('off','imaq:saveobj:recursive');
                save([handles.DataPath.String filesep 'handles.mat'],'handles')
                warning('on','MATLAB:Figure:FigureSavedToMATFile');
                warning('on','imaq:saveobj:recursive');
            
                % check if server location is available and create folder for behavioral data
                % 'open' indicates that this is the folder that relates to the current imaging session
                if exist(handles.serverPath,'dir')
                    fPath = get(handles.DataPath,'string');
                    fPath = strrep(fPath,fPath(1:2),handles.serverPath); %replace path of local drive with network drive
                    fPath = [fPath '_open']; %add identifier that this session is currently being acquired (useful to point apps to the same folder)
                    mkdir(fPath); %this is the server folder where other programs can write behavioral data.
                end
            
                %% start data acquisition
                stoppreview(handles.vidObj); %stop preview
                stop(handles.vidObj);
                flushdata(handles.vidObj);
                start(handles.vidObj); %get camera ready to be triggered
                StateCheck = true; %flag to control acquisition mode
                handles.lockGUI.Value = true; handles = lockGUI_Callback(handles.lockGUI, [], handles); %run callback for lock button
            
                % inactivate some parts of the GUI so they dont mess with the recording
                set(findall(handles.ExperimentID, '-property', 'enable'), 'enable', 'off')
                set(findall(handles.ControlPanel, '-property', 'enable'), 'enable', 'inactive')
                handles.FrameRate.Enable = 'off';
                handles.sBinning.Enable = 'off';
                handles.driveSelect.Enable = 'off';
                handles.ChangeDataPath.Enable = 'off';
            
                % automatically unlock software triggers if NI device is missing
                handles.triggerLock.Enable = 'on';
                if isempty(handles.dNIdevice)
                    handles.triggerLock.Value = false;
                    handles = triggerLock_Callback(handles.triggerLock, [], handles); %unlock software triggers
                end
            
                while StateCheck
                    %% check if still recording here
                    drawnow %update GUI inputs
                    StateCheck = logical(get(handles.WaitForTrigger, 'value')); %check WaitForTrigger to determine whether acqusition should still be active
            
                    if ~StateCheck %leave acqusition mode and reset indicators
                        disp('Acqusition stopped');
                        if exist('fPath','var')
                            movefile(fPath,strrep(fPath, '_open', '')); %rename data folder on the server. Later, imaging data can be moved there using 'Widefield_MoveData'.
                        end
            
                        % lock software trigger buttons again
                        handles.triggerLock.Value = true;
                        handles = triggerLock_Callback(handles.triggerLock, [], handles); %unlock software triggers
                        handles.triggerLock.Enable = 'off';
            
                        set(handles.WaitForTrigger, 'String' , 'Wait for Trigger OFF')
                        set(handles.WaitForTrigger, 'BackgroundColor' , '[1 0 0]')
                        set(handles.AcqusitionStatus, 'value' , false)
                        set(handles.AcqusitionStatus, 'String' , 'Inactive')
                        set(handles.AcqusitionStatus, 'BackgroundColor' , '[1 0 0]')
                        handles.CurrentStatus.String = 'Snapshot taken';
                        set(findall(handles.ExperimentID, '-property', 'enable'), 'enable', 'on')
                        set(findall(handles.ControlPanel, '-property', 'enable'), 'enable', 'on')
                        handles.FrameRate.Enable = 'on';
                        if contains(handles.vidName, 'pcocameraadaptor')
                            handles.sBinning.Enable = 'on';
                        end
                        handles.driveSelect.Enable = 'on';
                        handles.ChangeDataPath.Enable = 'on';
                        CheckPath(app, handles);
            
                        % stop camera
                        stop(handles.vidObj);
                        flushdata(handles.vidObj);
            
                        return
                    end
            
                    %% wait for trial trigger
                    data = false(1,3);
                    if ~isempty(handles.dNIdevice)
                        data = inputSingleScan(handles.dNIdevice); %trigger lines
                    end
                    MaxWait = str2double(get(handles.WaitingTime,'String')); %get maximum waiting time.
                    aTrigger = logical(data(1)); %trigger to start data acqusition
                    if sum(data) == length(data)
                        aTrigger = false; %do not initate if all triggers are high together (rarely happens due to noise in the recording).
                    end
            
                    %check trial trigger button
                    if ~aTrigger
                        aTrigger = handles.TrialTrigger.Value;
                        handles.TrialTrigger.Value = false;
                    end
            
                    removedFrames = 0; %keep track of removed frames
                    if aTrigger
                        tic; %timer to abort acquisition if stimulus is not received within a certain time limit
                        set(handles.TrialNr,'String',num2str(str2double(get(handles.TrialNr,'String'))+1)); %increase TrialNr;
            
                        set(handles.CurrentStatus,'String','Recording baseline'); %update status indicator
                        set(handles.AcqusitionStatus, 'value', true); %set acquisition status to active
                        set(handles.AcqusitionStatus, 'String' , 'Recording')
                        handles.BlueLight.Value = true; BlueLight_Callback(handles.BlueLight, [], handles) %switch LED on
                        handles.lockGUI.Value = true; handles = lockGUI_Callback(handles.lockGUI, [], handles); %run callback for lock button
                        drawnow;
            
                        if ~isempty(handles.dNIdevice)
                            aID = fopen([get(handles.DataPath,'String') filesep 'Analog_' get(handles.TrialNr,'String') '.dat'], 'wb'); %open binary file for analog data
                            handles.aListen = addlistener(handles.aNIdevice,'DataAvailable', @(src, event)logAnalogData(app, src,event,aID,handles.AcqusitionStatus)); %listener to stream analog data to disc
                            handles.aNIdevice.startBackground(); %start analog data streaming
                        end
            
                        if contains(handles.vidName, 'pcocameraadaptor')
                            frameRate = str2double(handles.FrameRate.String);
                        else
                            frameRate = str2double(handles.FrameRate.String{handles.FrameRate.Value});
                        end
                        bSize = ceil(str2double(handles.BaselineFrames.String)*frameRate); %number of frames in baseline
                        sSize = ceil(str2double(handles.PostStimFrames.String)*frameRate); %number of frames after stimulus trigger
            
                        pause(0.2); %make sure light and analog streaming are on before triggering the camera
                        handles.vidObj.FramesPerTrigger = Inf; %acquire until stoppped
                        trigger(handles.vidObj); %start image acquisition
            
                        while handles.AcqusitionStatus.Value %keep running until poststim data is recorded
                            if ~isempty(handles.dNIdevice)
                                data = inputSingleScan(handles.dNIdevice); %trigger lines
                            else
                                data = false(1,3);
                            end
                            if sum(data) == length(data)
                                stimTrigger = false; %do not proceed if all triggers are high
                            else
                                stimTrigger = logical(data(2)); %trigger that indicates start of stimulus presentation
                                stopTrigger = logical(data(3)); %trigger that indicates end of trial. aborts frame acquisition of read before all poststim frames have been collected.
            
                                %check buttons
                                if ~stimTrigger
                                    drawnow;
                                    stimTrigger = handles.StimTrigger.Value;
                                    handles.StimTrigger.Value = false;
                                end
                                if ~stopTrigger
                                    drawnow;
                                    stopTrigger = handles.StopTrigger.Value;
                                    handles.StopTrigger.Value = false;
                                end
                            end
            
                            if ~stimTrigger && (toc < MaxWait) && ~stopTrigger %record baseline frames until stimulus trigger occurs or maximum waiting time is reached
                                if handles.vidObj.FramesAvailable > bSize*2
                                    [~,rejFrames] = getdata(handles.vidObj,bSize); %remove unnecessary frames from video capture stream
                                    disp(['Waiting... Removing ' num2str(length(rejFrames)) ' frames from buffer']);
                                    removedFrames = removedFrames + length(rejFrames); %count removed frames
                                end
                            else
                                bIdx = handles.vidObj.FramesAvailable; %check available baseline frames
                                if toc < MaxWait && ~stopTrigger %record poststim if stimulus trigger was received
                                    set(handles.CurrentStatus,'String','Recording PostStim');drawnow;
                                    FrameWait = true;
                                    while FrameWait %wait until post-stim frames are captured
                                        FrameWait = handles.vidObj.FramesAvailable < (bIdx+sSize); %stop condition
                                        if ~isempty(handles.dNIdevice)
                                            data = inputSingleScan(handles.dNIdevice); %hardware trigger lines
                                            stopTrigger = logical(data(3));
                                        else
                                            data = false(1,3);
                                            stopTrigger = false;
                                        end
            
                                        %check stop button
                                        if ~stopTrigger
                                            drawnow;
                                            stopTrigger = handles.StopTrigger.Value;
                                            handles.StopTrigger.Value = false;
                                        end
            
                                        if sum(data) ~= length(data)
                                            if stopTrigger %trigger that indicates if end of trial has been reached
                                                FrameWait = false;
                                                disp(['Received stop trigger. Stopped after ' num2str(handles.vidObj.FramesAvailable-bIdx) ' poststim frames']);
                                            end
                                        end
                                    end
                                elseif stopTrigger
                                    disp('Received stop trigger. No poststim data recorded'); drawnow;
                                else
                                    disp('Maximum waiting time reached. No poststim data recorded'); drawnow;
                                end
            
                                % switch off LED and grab some extra dark frames to ensure that blue and violet channels can be separated correctly.
                                recPause = (handles.extraFrames + 1) / str2double(handles.FrameRate.String); %pause long enough to get extra frames.
                                if recPause < 0.2; recPause = 0.2; end %at least 200ms pause
            
                                handles.BlueLight.Value = false; BlueLight_Callback(handles.BlueLight, [], handles) %switch LED off
            
                                if ~isempty(handles.dNIdevice)
                                    pause(recPause); handles.aNIdevice.stop(); %pause to ensure all analog data is written, then stop analog object
                                    fclose(aID); %close analog data file
                                    delete(handles.aListen); %delete listener for analog data recording
                                end
                                stop(handles.vidObj); %stop video capture
            
                                if (bIdx-bSize) > 0
                                    [~, rejFrames] = getdata(handles.vidObj,bIdx-bSize); %remove unnecessary frames from video object
                                    disp(['Trial finished... Removing ' num2str(length(rejFrames)) ' frames from buffer']);
                                    removedFrames = removedFrames + length(rejFrames); %count removed frames
                                end
            
                                if handles.vidObj.FramesAvailable < (bSize + sSize + handles.extraFrames)
                                    [Data,~,frameTimes] = getdata(handles.vidObj, handles.vidObj.FramesAvailable); %collect available video data
                                else
                                    [Data,~,frameTimes] = getdata(handles.vidObj, bSize + sSize + handles.extraFrames); %collect requested video data
                                end
                                frameTimes = datenum(cat(1,frameTimes(:).AbsTime)); %collect absolute timestamps
            
                                if bIdx < bSize %if baseline has less frames as set in the GUI
                                    disp(['Collected only ' num2str(bIdx) ' instead of ' num2str(bSize) ' frames in the baseline. Not enough time between trial and stimulus trigger.'])
                                else
                                    bIdx = bSize;
                                end
                                set(handles.AcqusitionStatus, 'value', false); %stop recording
                                set(handles.AcqusitionStatus, 'String' , 'Waiting')
            
                            end
                        end
            
                        %% Save data to folder and clear
                        preStim = bIdx; %number of prestim frames
                        postStim = size(Data,4)-bIdx; %number of poststim frames
            
                        set(handles.CurrentStatus,'String','Saving data');
                        disp(['Trial ' get(handles.TrialNr,'String') '; Baseline Frames: ' num2str(preStim) '; Poststim Frames: ' num2str(postStim) '; Dark Frames: ' num2str(handles.extraFrames) '; Saving data ...'])
            
                        % save frametimes and size of widefield data (this is useful to read binary data later)
                        imgSize = size(Data);
                        cFile = ([get(handles.DataPath,'String') filesep 'frameTimes_' num2str(str2double(handles.TrialNr.String), '%04i') '.mat']);
                        save(cFile, 'frameTimes', 'imgSize', 'removedFrames', 'preStim', 'postStim'); %save frametimes
            
                        numChans = 1 + (handles.lightMode.Value == 3); %two channels if lightmode is set to 'mixed'
            
                        cFile = sprintf('%s%cFrames_%d_%d_%d_%s_%s', get(handles.DataPath,'String'), ...
                            filesep, numChans, size(Data,2), size(Data,1), class(Data), num2str(str2double(handles.TrialNr.String), '%04i')); %name for imaging data file
            
                        if ~handles.saveTIF.Value %saw as raw binary (default because of high writing speed)
                            sID = fopen([cFile '.dat'], 'Wb'); %open binary stimulus file
                            fwrite(sID,Data,'uint16'); %write iamging data as flat binary
                            fclose(sID);
            
                        else %save as tiff stack (writes slower but raw data is more accessible)
                            for x = 1 : imgSize(end)
                                if length(imgSize) == 3
                                    imwrite(Data(:, :, x), [cFile '.tif'], 'WriteMode', 'append', 'Compression', 'none');
                                elseif length(imgSize) == 4
                                    imwrite(Data(:, :, :, x), [cFile '.tif'], 'WriteMode', 'append', 'Compression', 'none');
                                end
                            end
                        end
            
                        % show average of current trial
                        baselineAvg = squeeze(mean(Data(:,:,1,1:bIdx),4));
                        stimAvg = squeeze(mean(Data(:,:,1,bIdx+1:end),4));
                        stimAvg = (stimAvg-baselineAvg)./baselineAvg;
                        imshow(mat2gray(stimAvg),'parent',handles.ImagePlot);
                        colormap(handles.ImagePlot, parula(256));
                        drawnow;
                        clear Data frameTimes
            
                        disp(['Trial ' handles.TrialNr.String ' completed.']); toc;
                        disp('==================================================');
            
                        start(handles.vidObj); %get camera ready to be triggered again
                        set(handles.CurrentStatus,'String','Waiting for trigger');
                        handles.lockGUI.Value = false; handles = lockGUI_Callback(handles.lockGUI, [], handles); %run callback for lock button
            
                    end
                end
            end
        end
        
        function handles = CheckPath(app, handles)
            
            % Look for single-letter drives, starting at a: or c: as appropriate
            try
                if ispc
                ret = {};
                for i = double('c') : double('z')
                    if exist(['' i ':' filesep], 'dir') == 7
                        ret{end+1} = [i ':']; %#ok<AGROW>
                    end
                end
            
                elseif ismac
                   ret = dir('/Volumes/');
                   ret = {ret(3:end).name};
                end
                handles.driveSelect.String = char(ret);
            end
            
            cPath = java.io.File(strtrim(handles.driveSelect.String(handles.driveSelect.Value,:))); %make sure this is OS independent
            if (cPath.getFreeSpace / 2^30) < handles.minSize && length(handles.driveSelect.String) > 1 && cPath.getFreeSpace > 0
                answer = questdlg(['Only ' num2str((cPath.getFreeSpace / 2^30)) 'gb left on ' strtrim(handles.driveSelect.String(handles.driveSelect.Value,:)) filesep '. Change drive?'], ...
                    'Drive select', 'Yes', 'No', 'No, stop asking me', 'Yes');
                if strcmp(answer, 'Yes')
                    checker = true;
                    cDrive = handles.driveSelect.Value; %keep current drive index
                    while checker
                        handles.driveSelect.Value = rem(handles.driveSelect.Value,length(handles.driveSelect.String))+1; %increase drive selection value by 1
                        cPath = java.io.File(strtrim(handles.driveSelect.String(handles.driveSelect.Value,:)));
                        if (cPath.getFreeSpace / 2^30) > handles.minSize
                            disp(['Changed path to drive ' strtrim(handles.driveSelect.String(handles.driveSelect.Value,:)) filesep '. ' num2str((cPath.getFreeSpace / 2^30)) 'gb remaining.'])
                            checker = false;
                        elseif handles.driveSelect.Value == cDrive
                            disp(['Could not find a drive with more then ' num2str(handles.minSize) 'gb of free space. Path unchanged.'])
                            checker = false;
                        end
                    end
                elseif strcmp(answer, 'No, stop asking me')
                    handles.minSize = 0; %don't check disk size anymore
                end
            end
            
            % set basepath and look for present animals, experiment types and past recordings
            handles.path.base = [char(cPath) filesep ...
                strtrim(handles.RecordMode.String{handles.RecordMode.Value}) filesep]; %set path of imaging code
            
            if ~exist([handles.path.base 'Animals'],'dir') %check for animal path to save data
                mkdir([handles.path.base 'Animals']) %create folder if required
            end
            
            handles.AnimalID.String = cellstr(handles.AnimalID.String);
            folders = dir([handles.path.base 'Animals']); %find animal folders
            folders = folders([folders.isdir] & ~strncmpi('.', {folders.name}, 1));
            checker = true;
            for iAnimals = 1:size(folders,1) %skip first two entries because they contain folders '.' and '..'
                AllAnimals{iAnimals} = folders(iAnimals).name; %get animal folders
                if checker
                    if strcmp(handles.AnimalID.String{handles.AnimalID.Value},folders(iAnimals).name) %check if current selected animal coincides with discovered folder
                        handles.AnimalID.Value = iAnimals; %keep animal selection constant
                        checker = false;
                    end
                end
            end
            
            if isempty(iAnimals) %Check if any animals are found
                AllAnimals{1} = 'Dummy Subject'; %create dummy animal if nothing else is found
                mkdir([handles.path.base 'Animals' filesep 'Dummy Subject']) %create folder for default experiment
            end
            
            handles.AnimalID.String = AllAnimals; %update AnimalID selection
            if handles.AnimalID.Value > length(AllAnimals)
                handles.AnimalID.Value = 1; %reset indicator
            end
            if ~isempty(handles.AnimalID.Value)
                handles.path.AnimalID = AllAnimals{handles.AnimalID.Value}; %update path for current animal
            end
            
            folders = dir([handles.path.base 'Animals' filesep AllAnimals{handles.AnimalID.Value}]); %find Experiment folders
            folders = folders([folders.isdir] & ~strncmpi('.', {folders.name}, 1));
            for iExperiments = 1:size(folders,1) %skip first two entries because they contain folders '.' and '..'
                AllExperiments{iExperiments} = folders(iExperiments).name; %get experiment folders
            end
            if isempty(iExperiments) %Check if any experiments are found
                AllExperiments{1} = 'Default'; %create default experiment if nothing else is found
                mkdir([handles.path.base 'Animals' filesep AllAnimals{1} filesep 'Default']) %create folder for default experiment
            end
            
            handles.ExperimentType.String = AllExperiments; %update experiment type selection
            if size(AllExperiments,2) < handles.ExperimentType.Value; handles.ExperimentType.Value = 1; end
            handles.path.ExpType = AllExperiments{handles.ExperimentType.Value}; %update path for current experiment type
            cPath = [handles.path.base 'Animals' filesep AllAnimals{handles.AnimalID.Value} filesep AllExperiments{handles.ExperimentType.Value}]; %assign current path
            
            if exist([cPath filesep date], 'dir') && length(dir([cPath filesep date])) > 2 %check if folder for current date exist already and contains data
                Cnt = 1;
                while exist([cPath filesep date '_' num2str(Cnt)],'dir')
                    Cnt = Cnt +1; %update counter until it is ensured that current experiment name is not used already
                end
                handles.path.RecordingID = [date '_' num2str(Cnt)]; %set folder for recording day as the date + neccesarry counter
            else
                handles.path.RecordingID = date; %set folder for current recording to recording date
            end
            handles.RecordingID.String = handles.path.RecordingID; %update GUI
            handles.DataPath.String = [cPath filesep handles.path.RecordingID]; %set complete file path for data storage
            set(handles.TrialNr,'String','0'); %reset TrialNr
            handles.SnapshotTaken = false; %flag for snapshot - has to be taken in order to start data acquisition
            handles.CurrentStatus.String = 'Not ready'; %reset status indicator
            guidata(handles.WidefieldImager, handles);
        end
        
        function vidObj = checkCamera(app, adaptorName, handles)
            % function to check camera settings
            
            % check videoadapter, set settings from the GUI and start video preview.
            % Returns an empty object if 'adaptorname' is not available.
            vidObj = [];
            try
                imaqreset
                vidObj = videoinput(adaptorName); %get video object
                src = getselectedsource(vidObj);
            
                if contains(adaptorName, 'pcocameraadaptor') %check for PCO camera and set specific settings
                    clockSpeed = set(src,'PCPixelclock_Hz');
                    [~,idx] = max(str2num(cell2mat(clockSpeed))); %get fastest clockspeed
                    src.PCPixelclock_Hz = clockSpeed{idx}; %fast scanning mode
                    src.E2ExposureTime = 1000/str2double(handles.FrameRate.String) * 1000; %set framerate
                    binFact = 4; %set 4x binning by default
            
                    try %uses different string in different adaptor versions...
                        src.B1BinningHorizontal = num2str(binFact);
                        src.B2BinningVertical = num2str(binFact);
                    catch
                        src.B1BinningHorizontal = num2str(binFact,'%02i');
                        src.B2BinningVertical = num2str(binFact,'%02i');
                    end
                else
                    try
                        handles.FrameRate.Style = 'popupmenu'; %change menu style to indicate available frame rates
                        handles.FrameRate.String = set(src, 'FrameRate');
                        src.FrameRate = handles.FrameRate.String{1}; %use highest available frame rate
                    catch
                        handles.FrameRate.String = {'30'};
                    end
                end
            
                %setup and display live video feed in preview window
                vidRes = get(vidObj,'VideoResolution');
                nbands = get(vidObj,'NumberOfBands');
                handles.CurrentResolution.String = [num2str(vidRes(1)) ' x ' num2str(vidRes(2))]; %update current resolution indicator
            %     imshow(zeros(vidRes(2),vidRes(1),nbands),[],'parent',handles.ImagePlot,'XData',[0 1],'YData',[0 1]); %create image object for preview
                imshow(zeros(vidRes(2),vidRes(1),nbands),[],'parent',handles.ImagePlot); %create image object for preview
            
                %set default camera configuration
                handles.ROIposition = [0 0 vidRes];  %default ROIposition
                set(vidObj,'TriggerFrameDelay',0);
                set(vidObj,'FrameGrabInterval',1);
                set(vidObj,'TriggerRepeat',0);
                set(vidObj,'ROIposition',handles.ROIposition);
                set(vidObj,'FramesPerTrigger',Inf);
                triggerconfig(vidObj,'manual');
            
            catch
                vidObj = [];
            end
        end
        
        function closeCalibration(app, handles)
            % this is when the calibration figure gets closed
            
            handles.CalibrationMode.Value = false;
            CalibrationMode_Callback(handles.CalibrationMode, [], handles); drawnow;
            delete(gcf);
        end
        
        function edit11_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit11 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function logAnalogData(app, src, evt, fid, flag)
            % Add the time stamp and the data values to data. To write data sequentially,
            % transpose the matrix.
            % Modified to use an additional flag to stop ongoing data acquistion if
            % false. Flag should be a handle to a control that contains a logical
            % value.
            %
            % Example for addding function to a listener when running analog through StartBackground:
            % handles.aListen = handles.aNIdevice.addlistener('DataAvailable', @(src, event)logAnalogData(src,event,aID,handles.AcqusitionStatus)); %listener to stream analog data to disc
            
            
            if src.IsRunning %only execute while acquisition is still active
                if evt.TimeStamps(1) == 0
                    fwrite(fid,3,'double'); %indicate number of single values in the header
                    fwrite(fid,evt.TriggerTime,'double'); %write time of acquisition onset on first run
                    fwrite(fid,size(evt.Data,2)+1,'double'); %write number of recorded analog channels + timestamps
                    fwrite(fid,inf,'double'); %write number of values to read (set to inf since absolute recording duration is unknown at this point)
                end
            
                data = [evt.TimeStamps*1000, evt.Data*1000]' ; %convert time to ms and voltage to mV
                fwrite(fid,uint16(data),'uint16');
                %     plot(data(1,:),data(2:end,:))
            
                if ~logical(get(flag, 'value')) %check if acqusition is still active
                    src.stop(); %stop recording
                end
            end
        end
        
        function plotCalibration(app, handles)
            % this is to update the contents of the calibration figure
            
            % get image and center
            frame = getsnapshot(handles.vidObj);
            frame = frame(:,:,1);
            midFrame = round(size(frame)/2);
            
            % plot each line
            ax = handles.Calibration.Children(2);
            plot(ax, frame(midFrame(1), :))
            ax.TickLength = [0 0]; ax.XTickLabel = []; ax.YTickLabel = [];
            xlim(ax,[1 size(frame,2)]); ylim(ax,[0 intmax(class(frame))]);
            title(ax,'Horizontal axis', 'FontSize', 15);
            
            ax = handles.Calibration.Children(1);
            plot(ax, frame(:, midFrame(2)))
            ax.TickLength = [0 0]; ax.XTickLabel = []; ax.YTickLabel = [];
            xlim(ax,[1 size(frame,1)]); ylim(ax,[0 intmax(class(frame))]);
            title(ax,'Vertical axis', 'FontSize', 15);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function WidefieldImager_OpeningFcn(app, varargin)
            % --- Executes just before WidefieldImager is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.WidefieldImager, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to WidefieldImager (see VARARGIN)
            
            % check if matlab is 2016b or newer
            handles.output = hObject;
            if datenum(version('-date')) < 736588 %check if matlab is 2016b or newer
                warning('Matlab version is older as 2016b. This code might break on earlier versions because of the "contains" function.')
            end
            
            % some variables
            handles.daqName = 'Dev1'; %name of the national instruments DAQ board
            handles.extraFrames = 5; %amount of frames where the light is switched off at the end of the recording. This helps to ensure proper alignment with analog data.
            handles.minSize = 100; % minimum free disk space before producing a warning (in GB)
            handles.serverPath = '\\your_server_path'; %data server path
            
            %% initialize NI card
            handles = RecordMode_Callback(app, []); %check recording mode to create correct ni object
            guidata(handles.WidefieldImager, handles);
            
            %% initialize camera and set handles
            info = imaqhwinfo;
            handles.vidName = [];
            camCheck = contains(info.InstalledAdaptors, 'pcocameraadaptor');
            
            if any(camCheck)
            handles.vidName = info.InstalledAdaptors{camCheck}; %PCO camera
            end
            handles.vidObj = checkCamera(app, handles.vidName, handles); %get PCO video object
            
            % check for other cameras if PCO adaptor is unavailable.
            if isempty(handles.vidObj)
                disp('PCO camera not available. Searching for other cameras.');
                handles.vidName = [];
                if length(info.InstalledAdaptors) == 1
                    handles.vidName = info.InstalledAdaptors{1};
                elseif length(info.InstalledAdaptors) > 1 %multiple adaptors present, ask for selection
                    out = listdlg('Name', 'Please select your camera', ...
                        'SelectionMode','single','liststring',info.InstalledAdaptors,'listsize',[300 300]);
                    if ~isempty(out)
                        handles.vidName = info.InstalledAdaptors{out};
                    end
                end
                handles.sBinning.Enable = 'off'; %this only works with PCO camera so disable for other cameras
            end
            
            if ~isempty(handles.vidName)
                fprintf('Using %s as current video adapter\n',handles.vidName);
                handles.vidObj = checkCamera(app, handles.vidName, handles); %get non-PCO video object
            
                if ~isempty(handles.vidObj)
                    preview(handles.vidObj,handles.ImagePlot.Children); %start preview
                    maxRange = floor(256*0.7); %limit intensity to 70% of dynamic range to avoid ceiling effects
                    cMap = gray(maxRange); cMap(end+1:256,:) = repmat([1 0 0 ],256-maxRange,1);
                    colormap(handles.ImagePlot,cMap);
                end
            end
            
            if isempty(handles.vidObj)
                warning('No camera found. Type "imaqhwinfo" to make sure your camera is porperly installed and running.')
            end
            
            if any(ismember(handles.driveSelect.String(:,1), 'g')) %start on G: drive by default
                handles.driveSelect.Value = find(ismember(handles.driveSelect.String(:,1), 'g'));
            end
            
            % set timer for calibration mode
            handles.Calibration = []; %placeholder for calibration mode
            CheckPath(app, handles);
        end

        % Value changed function: AcqusitionStatus
        function AcqusitionStatus_Callback(app, event)
            % --- Executes on button press in AcqusitionStatus.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to AcqusitionStatus (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of AcqusitionStatus
            if ~(get(handles.WaitForTrigger, 'value') && handles.SnapshotTaken)
                set(handles.AcqusitionStatus, 'value',false)
            end
        end

        % Value changed function: AnimalID
        function AnimalID_Callback(app, event)
            % --- Executes on selection change in AnimalID.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to AnimalID (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns AnimalID contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from AnimalID
            
            CheckPath(app, handles);
        end

        % Value changed function: BlueLight
        function BlueLight_Callback(app, event)
            % --- Executes on button press in BlueLight.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to BlueLight (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if isempty(handles.dNIdevice)
            %     disp('LED control not available - NI device is missing')
                set(hObject, 'Value',false)
            else
                if hObject.Value
                    out = false(1,3); out(handles.lightMode.Value) = true; %indicator for NI channel
                    outputSingleScan(handles.dNIdevice,out)
                    if handles.lightMode.Value == 1
                        hObject.BackgroundColor = [0 0 1];
                        hObject.String = 'BLUE is ON';
                    elseif handles.lightMode.Value == 2
                        hObject.BackgroundColor = [.5 0 .5];
                        hObject.String = 'VIOLET is ON';
                    elseif handles.lightMode.Value == 3
                        hObject.BackgroundColor = [.25 0 .75];
                        hObject.String = 'MIXED stim is ON';
                    end
                else
                    outputSingleScan(handles.dNIdevice,false(1,3))
                    hObject.BackgroundColor = zeros(1,3);
                    hObject.String = 'LED OFF';
                end
            end
        end

        % Value changed function: CalibrationMode
        function CalibrationMode_Callback(app, event)
            % --- Executes on button press in CalibrationMode.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to CalibrationMode (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of CalibrationMode
            
            if hObject.Value
            
                %make sure preview is on
                handles.StartPreview.Value = true;
                StartPreview_Callback(handles.StartPreview, [], handles);
            
                % get image and center
                frame = getsnapshot(handles.vidObj);
                frame = frame(:,:,1);
                midFrame = round(size(frame)/2);
            
                % plot lines to preview window
                if size(handles.ImagePlot.Children,1) ~= 3
                    delete(findall(handles.ImagePlot,'Type','line'))
                    hold(handles.ImagePlot, 'on');
                    plot(handles.ImagePlot, [1, size(frame,2)], [midFrame(1) midFrame(1)],'r', 'linewidth', 2);
                    plot(handles.ImagePlot, [midFrame(2) midFrame(2)], [1, size(frame,1)],'r', 'linewidth', 2);
                    hold(handles.ImagePlot, 'off');
                end
            
                % open calibration window
                try
                    handles.Calibration.Children;
                catch
                    handles.Calibration = figure('name','Calibration window','CloseRequestFcn',@(src,evt)closeCalibration(app, handles));
                    subplot(2,1,1); subplot(2,1,2); handles.Calibration.MenuBar = 'none';
            
                    handles.updateCalibration = timer('Period',0.1,... %period
                                                      'ExecutionMode','fixedRate',... %{singleShot,fixedRate,fixedSpacing,fixedDelay}
                                                      'BusyMode','drop',... %{drop, error, queue}
                                                      'TasksToExecute',inf,...
                                                      'StartDelay',0,...
                                                      'TimerFcn',@(src,evt)plotCalibration(app, handles));
                    start(handles.updateCalibration);
                    guidata(handles.WidefieldImager, handles);
                end
            else
                try
                    delete(findall(handles.ImagePlot,'Type','line'))
                    stop(handles.updateCalibration);
                    close(handles.Calibration);
                end
            end
        end

        % Button pushed function: ChangeDataPath
        function ChangeDataPath_Callback(app, event)
            % --- Executes on button press in ChangeDataPath.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ChangeDataPath (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles.DataPath.String = uigetdir;
        end

        % Value changed function: CurrentResolution
        function CurrentResolution_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to CurrentResolution (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of CurrentResolution as text
            %        str2double(get(hObject,'String')) returns contents of CurrentResolution as a double
            
            if handles.vidObj == 0
                disp('ROI change not available. Check if camera is connected and restart.')
            else
                S = get(hObject,'String'); %get resolution string
                S(strfind(S,'x'):strfind(S,'x')+1)=[]; %remove 'x' from string
                nRes = str2num(S);
                vidRes = get(handles.vidObj,'VideoResolution');
            
                if length(nRes) == 2 && nRes(1)<=vidRes(2) && nRes(2)<=vidRes(1)
                    handles.ROIposition =[0 0 nRes(1) nRes(2)];
                    stop(handles.vidObj) %stop camera
                    set(handles.vidObj,'ROIposition',handles.ROIposition); % set new ROI position
                    colormap(handles.ImagePlot,'gray');
                    preview(handles.vidObj)  %resume camera preview
                    maxRange = floor(256*0.7); %limit intensity to 70% of dynamic range to avoid ceiling effects
                    cMap = gray(maxRange); cMap(end+1:256,:) = repmat([1 0 0 ],256-maxRange,1);
                    colormap(handles.ImagePlot,cMap);
                else
                    disp([get(hObject,'String') ' is not a valid input to change the resolution'])
                end
            end
        end

        % Value changed function: ExperimentType
        function ExperimentType_Callback(app, event)
            % --- Executes on selection change in ExperimentType.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ExperimentType (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns ExperimentType contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from ExperimentType
            
            CheckPath(app, handles);
        end

        % Value changed function: FrameRate
        function FrameRate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to FrameRate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of FrameRate as text
            %        str2double(get(hObject,'String')) returns contents of FrameRate as a double
            
            if ~isempty(handles.vidObj)
                stop(handles.vidObj);
                flushdata(handles.vidObj);
                src = getselectedsource(handles.vidObj);
                if contains(handles.vidName, 'pcocameraadaptor')
                    
                    if str2double(handles.FrameRate.String) > 10 && strcmp(handles.sBinning.String(handles.sBinning.Value),'1')
                        answer = questdlg('Spatial binning is set to 1. This could produce a lot of data. Proceed?');
                        if strcmpi(answer,'Yes')
                            src.E2ExposureTime = 1000/str2double(handles.FrameRate.String) * 1000; %set current framerate
                        end
                    else
                        src.E2ExposureTime = 1000/str2double(handles.FrameRate.String) * 1000; %set current framerate
                    end
                else
                    % Adjust frame rate for non-PCO camera.
                    % !! Warning !! This does not take effect with all imaq video adaptors.
                    % Make sure that your camera allows the Matlab adaptor to control the framerate.
                    src.FrameRate = hObject.String{hObject.Value};
                end
            end
        end

        % Button pushed function: NewAnimal
        function NewAnimal_Callback(app, event)
            % --- Executes on button press in NewAnimal.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to NewAnimal (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            handles = CheckPath(app, handles); %Check for data path, reset date and trialcount
            dPrompt = {'Enter animal ID'};
            pName = 'New animal';
            newMouse = inputdlg(dPrompt,pName,1,{'New mouse'});
            if ~isempty(newMouse)
                mkdir([handles.path.base 'Animals' filesep newMouse{1}])
            
                dPrompt = {'Enter experiment ID'};
                pName = 'New experiment';
            
                newExp = inputdlg(dPrompt,pName,1,{[strtrim(handles.RecordMode.String{handles.RecordMode.Value}) 'Paradigm']});
                mkdir([handles.path.base 'Animals' filesep newMouse{1} filesep newExp{1}])
            
                handles = CheckPath(app, handles); %Check for data path, reset date and trialcount
                handles.AnimalID.Value = find(ismember(handles.AnimalID.String,newMouse{1}));
                CheckPath(app, handles);
            end
        end

        % Button pushed function: NewExperiment
        function NewExperiment_Callback(app, event)
            % --- Executes on button press in NewExperiment.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to NewExperiment (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            dPrompt = {'Enter experiment ID'};
            pName = 'New experiment';
            newExp = inputdlg(dPrompt,pName,1,{'New experiment'});
            if ~isempty(newExp)
                mkdir([handles.path.base 'Animals' filesep handles.AnimalID.String{handles.AnimalID.Value} filesep newExp{1}])
            
                handles = CheckPath(app, handles); %Check for data path, reset date and trialcount
                handles.ExperimentType.Value = find(ismember(handles.ExperimentType.String,newExp{1}));
                CheckPath(app, handles);
            end
        end

        % Value changed function: RecordMode
        function RecordMode_Callback(app, event)
            % --- Executes on selection change in RecordMode.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to RecordMode (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns RecordMode contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from RecordMode
            
            handles.ExperimentType.Value = 1;
            handles.AnimalID.Value = 1;
            
            if hObject.Value == 1 %set standard settings for widefield mapping
                handles.BaselineFrames.String = '2';
                handles.PostStimFrames.String = '23';
            elseif  hObject.Value == 2 %set standard settings for behavioral recording
                handles.BaselineFrames.String = '3';
                handles.PostStimFrames.String = '6';
            end
            handles = CheckPath(app, handles);
            
            % check NI card
            try
                daqs = daq.getDevices;
            catch
                daqs = [];
            end
            
            if isempty(daqs)
                disp('No NI devices found or data acquisition toolbox missing.')
                disp('Use software triggers instead.')
                handles.dNIdevice = [];
            else
                if isfield(handles,'dNIdevice')
                    delete(handles.dNIdevice);
                    delete(handles.aNIdevice);
                end
            
                checker = false; %check if daq device with correct ID is present. Default is 'Dev1'
                for x = 1 : length(daqs)
                    if strcmpi(daqs(x).ID,handles.daqName)
                        checker = true;
                    end
                end
                if ~checker
                    warning(['Could not find specified DAQ: ' handles.daqName ' - Using existing board ' daqs(x).ID ' instead.'])
                    handles.daqName = daqs(x).ID;
                end
            
                handles.dNIdevice = daq.createSession(daqs(x).Vendor.ID); %object for communication with DAQ - digital lines
                handles.aNIdevice = daq.createSession(daqs(x).Vendor.ID); %object for communication with DAQ - analog lines
                handles.aNIdevice.IsContinuous = true; %set to continous acquisition
                handles.aNIdevice.Rate = 1000; %set sampling rate to 1kHz
            
                addDigitalChannel(handles.dNIdevice,handles.daqName,'port1/line0:2','OutputOnly'); %output channels for blue, violet and mixed light (1.0:blue, 1.1:violet, 1.2:mixed)
                outputSingleScan(handles.dNIdevice,false(1,3)); %make sure outputs are false
                addDigitalChannel(handles.dNIdevice,handles.daqName,'port0/line0:2','InputOnly'); %input channels to trigger data acquisition and control timing of animal behavior (0.2: trial start, 0.3: stim on)
            
                ch = addAnalogInputChannel(handles.aNIdevice,handles.daqName, 0:3, 'Voltage');
                for x = 1 : length(ch)
                    ch(x).TerminalConfig = 'SingleEnded'; %use single-ended recordings. Use differential when recording low SNR signals (needs to line for +/-)
                end
            end
            guidata(hObject,handles);
        end

        % Button pushed function: ResetROI
        function ResetROI_Callback(app, event)
            % --- Executes on button press in ResetROI.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ResetROI (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if handles.vidObj == 0
                disp('ROI change not available. Check if camera is connected and restart.')
            else
                vidRes = get(handles.vidObj,'VideoResolution');
                handles.ROIposition = [0 0 vidRes];  %default ROIposition
                stoppreview(handles.vidObj) %stop camera
                stop(handles.vidObj) %stop camera
                set(handles.vidObj,'ROIposition',handles.ROIposition); % set new ROI position
                snap = getsnapshot(handles.vidObj); %get snapshot from video object
            %     imshow(snap,[],'parent',handles.ImagePlot,'XData',[0 1],'YData',[0 1]); hold(handles.ImagePlot,'off'); %plot current view
                imshow(snap,[],'parent',handles.ImagePlot); hold(handles.ImagePlot,'off'); %plot current view
                colormap(handles.ImagePlot,'gray');
                preview(handles.vidObj,handles.ImagePlot.Children); %resume preview
                maxRange = floor(256*0.7); %limit intensity to 70% of dynamic range to avoid ceiling effects
                cMap = gray(maxRange); cMap(end+1:256,:) = repmat([1 0 0 ],256-maxRange,1);
                colormap(handles.ImagePlot,cMap);
            
                % update current resolution in GUI
                imHeight = handles.ROIposition(3)-handles.ROIposition(1);
                imWidth = handles.ROIposition(4)-handles.ROIposition(2);
                handles.CurrentResolution.String = [num2str(imWidth) ' x ' num2str(imHeight)]; %update current resolution indicator
            end
        end

        % Button pushed function: SelectROI
        function SelectROI_Callback(app, event)
            % --- Executes on button press in SelectROI.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to SelectROI (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if handles.vidObj == 0
                disp('ROI change not available. Check if camera is connected and restart.')
            else
                %% select ROI
                closepreview(handles.vidObj) %stop camera, and set new ROI position
                stop(handles.vidObj) %stop camera
                snap = getsnapshot(handles.vidObj);
                imshow(snap,'Parent',handles.ImagePlot);
                [~,ROI] = imcrop(handles.ImagePlot);
                ROI = floor(ROI);
            
                if ~isempty(ROI)
                    handles.CurrentResolution.String = [num2str(ROI(3)) ' x ' num2str(ROI(4))]; %update current resolution indicator
                    h = figure; %create temporary figure for saving the roi selection
            %         imshow(snap,'XData',[0 1],'YData',[0 1]); hold on %plot current view
                    imshow(snap); hold on %plot current view
                    xVals = [repmat(ROI(1),1,2) repmat(ROI(1)+ROI(3),1,2) ROI(1)];
                    yVals = [ROI(2) repmat(ROI(2)+ROI(4),1,2) repmat(ROI(2),1,2)];
                    plot(xVals,yVals,'r','linewidth',2) %plot ROI outline
                    savefig(gcf,[handles.path.base 'ROI.fig']) %save ROI figure
                    saveas(h,[handles.path.base 'ROI.jpg']) %save ROI as jpg
                    close
            
                    %update preview
                    handles.ROIposition = ROI;
                    set(handles.vidObj,'ROIposition',handles.ROIposition);
                    snap = getsnapshot(handles.vidObj); hold(handles.ImagePlot,'off');
                    %         imshow(snap,[],'parent',handles.ImagePlot,'XData',[0 1],'YData',[0 1]);
                    imshow(snap,[],'parent',handles.ImagePlot);
                end
            
                maxRange = floor(256*0.7); %limit intensity to 70% of dynamic range to avoid ceiling effects
                cMap = gray(maxRange); cMap(end+1:256,:) = repmat([1 0 0 ],256-maxRange,1);
                colormap(handles.ImagePlot,cMap);
                preview(handles.vidObj,handles.ImagePlot.Children); %resume preview
            end
        end

        % Button pushed function: StartPreview
        function StartPreview_Callback(app, event)
            % --- Executes on button press in StartPreview.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to StartPreview (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if handles.vidObj == 0
                disp('Preview not available. Check if camera is connected and restart.')
            else
                preview(handles.vidObj,handles.ImagePlot.Children); %start preview
                maxRange = floor(256*0.7); %limit intensity to 70% of dynamic range to avoid ceiling effects
                cMap = gray(maxRange); cMap(end+1:256,:) = repmat([1 0 0 ],256-maxRange,1);
                colormap(handles.ImagePlot,cMap);
            end
        end

        % Button pushed function: StopPreview
        function StopPreview_Callback(app, event)
            % --- Executes on button press in StopPreview.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to StopPreview (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if ~(handles.vidObj == 0)
                stoppreview(handles.vidObj); %stop preview
            end
        end

        % Button pushed function: TakeSnapshot
        function TakeSnapshot_Callback(app, event)
            % --- Executes on button press in TakeSnapshot.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to TakeSnapshot (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if handles.vidObj == 0
                disp('Snapshot not available. Check if camera is connected and restart.')
            else
                h = figure('Toolbar','none','Menubar','none','NumberTitle','off','Name','Snapshot'); %create figure to show snapshot
                snap = getsnapshot(handles.vidObj); %get snapshot from video object
                temp = ls(handles.path.base); %check if earlier snapshots exist
                temp(1,8)=' '; % make sure temp has enough characters
                temp = temp(sum(ismember(temp(:,1:8),'Snapshot'),2)==8,:); %only keep snapshot filenames
                temp(~ismember(temp,'0123456789')) = ' '; %replace non-integer characters with blanks
                cNr = max(str2num(temp)); %get highest snapshot nr
                cNr(isempty(cNr)) = 0; %replace empty with 0 if no previous snapshot existed
                save([handles.path.base 'Snapshot_' num2str(cNr+1) '.mat'],'snap') % snapshot
                imwrite(mat2gray(snap),[handles.path.base 'Snapshot_' num2str(cNr+1) '.jpg']) %save snapshot as jpg
            
                %     imshow(snap,'XData',[0 1],'YData',[0 1]); colormap gray; axis image;
                imshow(snap); axis image; title(['Saved as Snapshot ' num2str(cNr+1)]);
                uicontrol('String','Close','Callback','close(gcf)','units','normalized','position',[0 0 0.15 0.07]); %close button
                handles.SnapshotTaken = true; %update snapshot flag
            
                % change status indicator
                if handles.WaitForTrigger.Value %Waiting for trigger and snapshot taken
                    handles.AcqusitionStatus.BackgroundColor = [0 1 0];
                    handles.AcqusitionStatus.String = 'Waiting';
                    handles.CurrentStatus.String = 'Waiting for trigger';
                    AcquireData(app, handles.WidefieldImager); %set to acquisition mode
            
                elseif ~handles.WaitForTrigger.Value %Not waiting for trigger but snapshot is taken
                    handles.AcqusitionStatus.Value = false;
                    handles.AcqusitionStatus.BackgroundColor = [1 0 0];
                    set(handles.AcqusitionStatus, 'String' , 'Inactive')
                    handles.CurrentStatus.String = 'Snapshot taken';
                end
                guidata(hObject,handles);
            end
        end

        % Value changed function: WaitForTrigger
        function WaitForTrigger_Callback(app, event)
            % --- Executes on button press in WaitForTrigger.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to WaitForTrigger (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of WaitForTrigger
            
            if get(hObject, 'Value')
                set(hObject, 'String' , 'Wait for Trigger ON')
                set(hObject, 'BackgroundColor' , '[0 1 0]')
            else
                set(hObject, 'String' , 'Wait for Trigger OFF')
                set(hObject, 'BackgroundColor' , '[1 0 0]')
            end
            
            % change status indicator
            if handles.WaitForTrigger.Value && handles.SnapshotTaken %Waiting for trigger and snapshot taken
                handles.AcqusitionStatus.BackgroundColor = [0 1 0];
                handles.AcqusitionStatus.String = 'Waiting';
                handles.CurrentStatus.String = 'Waiting for trigger';
                AcquireData(app, handles.WidefieldImager); %set to acquisition mode
            elseif handles.WaitForTrigger.Value && ~handles.SnapshotTaken %Waiting for trigger but no snapshot taken
                handles.AcqusitionStatus.Value = false;
                handles.AcqusitionStatus.BackgroundColor = [1 0 0];
                set(handles.AcqusitionStatus, 'String' , 'Inactive')
                handles.CurrentStatus.String = 'No snapshot taken';
                disp('Please press the TAKE SNAPSHOT button. Recording will only start when at least one snapshot is taken.')
            elseif ~handles.WaitForTrigger.Value && handles.SnapshotTaken %Not waiting for trigger but snapshot is taken
                handles.AcqusitionStatus.Value = false;
                handles.AcqusitionStatus.BackgroundColor = [1 0 0];
                set(handles.AcqusitionStatus, 'String' , 'Inactive')
                handles.CurrentStatus.String = 'Snapshot taken';
            elseif ~handles.WaitForTrigger.Value && ~handles.SnapshotTaken %Not waiting for trigger and no snapshot is taken
                handles.AcqusitionStatus.Value = false;
                handles.AcqusitionStatus.BackgroundColor = [1 0 0];
                set(handles.AcqusitionStatus, 'String' , 'Inactive')
                handles.CurrentStatus.String = 'Not ready';
            end
        end

        % Close request function: WidefieldImager
        function WidefieldImager_CloseRequestFcn(app, event)
            % --- Executes when user attempts to close IntrinsicImagerGUI.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to WidefieldImagerGUI (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %% move saved files to data save location
            sFiles = dir([handles.path.base '*Snapshot*']);
            rFiles = dir([handles.path.base '*ROI*']);
            aFiles = [{sFiles.name} {rFiles.name}];
            
            if ~exist(get(handles.DataPath,'String'), 'dir') %create data path if not existent
                mkdir(get(handles.DataPath,'String'))
            end
            for iFiles = 1:length(aFiles)
                movefile([handles.path.base aFiles{iFiles}],[get(handles.DataPath,'String') filesep aFiles{iFiles}]); %move files
            end
            
            %% ensure calibration mode is off
            handles.CalibrationMode.Value = false;
            CalibrationMode_Callback(handles.CalibrationMode, [], handles); drawnow;
            
            %% clear running objects
            imaqreset
            guidata(hObject,handles);
            delete(hObject)
        end

        % Value changed function: driveSelect
        function driveSelect_Callback(app, event)
            % --- Executes on selection change in driveSelect.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to driveSelect (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns driveSelect contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from driveSelect
            
            a = strfind(handles.DataPath.String,filesep);
            cPath = [strtrim(handles.driveSelect.String(handles.driveSelect.Value,:)) fileparts(handles.DataPath.String(a(1):end))];
            if ~exist(cPath,'dir')
                mkdir(cPath);
            end
            handles = CheckPath(app, handles); %Check for data path, reset date and trialcount
            guidata(handles.WidefieldImager, handles);
        end

        % Value changed function: lightMode
        function lightMode_Callback(app, event)
            % --- Executes on selection change in lightMode.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to lightMode (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns lightMode contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from lightMode
            
            BlueLight_Callback(handles.BlueLight, [], handles) %switch LED
        end

        % Value changed function: lockGUI
        function lockGUI_Callback(app, event)
            % --- Executes on button press in lockGUI.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to lockGUI (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if islogging(handles.vidObj)
                hObject.Value = true;
                handles.WaitForTrigger.Enable = 'off';
                hObject.String = 'Locked';
                disp('Cant release recording control while acquiring data. Wait until trial is completed.')
            else
                if hObject.Value == 0
                    handles.WaitForTrigger.Enable = 'on';
                    hObject.String = 'Released';
                elseif hObject.Value == 1
                    handles.WaitForTrigger.Enable = 'off';
                    hObject.String = 'Locked';
                end
            end
        end

        % Value changed function: sBinning
        function sBinning_Callback(app, event)
            % --- Executes on selection change in sBinning.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sBinning (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns sBinning contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from sBinning
            
            if contains(handles.vidName, 'pcocameraadaptor')
                src = getselectedsource(handles.vidObj);
                if str2double(handles.FrameRate.String) > 10 && strcmp(hObject.String(hObject.Value),'1')
                    src.E2ExposureTime = 100000; %limit framerate to 10Hz
                    disp('FrameRate is limited to 10Hz without spatial binning.')
                else
                    src.E2ExposureTime = 1000/str2double(handles.FrameRate.String)*1000; %make sure current framerate is used
                end
            
                binFact = str2num(hObject.String(hObject.Value));
                try %uses different string in different adaptor versions...
                    src.B1BinningHorizontal = num2str(binFact);
                    src.B2BinningVertical = num2str(binFact);
                catch
                    src.B1BinningHorizontal = num2str(binFact,'%02i');
                    src.B2BinningVertical = num2str(binFact,'%02i');
                end
            
                vidRes = get(handles.vidObj,'VideoResolution');
                handles.CurrentResolution.String = [num2str(vidRes(1)) ' x ' num2str(vidRes(2))]; %update current resolution indicator
            end
        end

        % Value changed function: triggerLock
        function triggerLock_Callback(app, event)
            % --- Executes on button press in triggerLock.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to triggerLock (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of triggerLock
            
            handles.TrialTrigger.Value = false;
            handles.StimTrigger.Value = false;
            handles.StopTrigger.Value = false;
            
            if hObject.Value == 0
                handles.TrialTrigger.Enable = 'on';
                handles.StimTrigger.Enable = 'on';
                handles.StopTrigger.Enable = 'on';
                hObject.String = 'Released';
            elseif hObject.Value == 1
                handles.TrialTrigger.Enable = 'off';
                handles.StimTrigger.Enable = 'off';
                handles.StopTrigger.Enable = 'off';
                hObject.String = 'Locked';
            end
        end

        % Value changed function: edit16
        function ExposureTime_Callback(app, event)
            value = app.edit16.Value;
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to FrameRate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of FrameRate as text
            %        str2double(get(hObject,'String')) returns contents of FrameRate as a double
            
            if ~isempty(handles.vidObj)
                stop(handles.vidObj);
                flushdata(handles.vidObj);
                src = getselectedsource(handles.vidObj);
                if contains(handles.vidName, 'pcocameraadaptor')
                    if str2double(handles.FrameRate.String) > 10 && strcmp(handles.sBinning.String(handles.sBinning.Value),'1')
                        answer = questdlg('Spatial binning is set to 1. This could produce a lot of data. Proceed?');
                        if strcmpi(answer,'Yes')
                            src.E2ExposureTime = 1000/str2double(handles.FrameRate.String) * 1000; %set current framerate
                        end
                    else
                        src.E2ExposureTime = 1000/str2double(handles.FrameRate.String) * 1000; %set current framerate
                    end
                else
                    % Adjust frame rate for non-PCO camera.
                    % !! Warning !! This does not take effect with all imaq video adaptors.
                    % Make sure that your camera allows the Matlab adaptor to control the framerate.
                    src.FrameRate = hObject.String{hObject.Value};
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WidefieldImager = uifigure('Visible', 'off');
            app.WidefieldImager.Name = 'WidefieldImager';
            app.WidefieldImager.CloseRequestFcn = createCallbackFcn(app, @WidefieldImager_CloseRequestFcn, true);
            app.WidefieldImager.Tag = 'WidefieldImager';
            app.ImagePlot = uiaxes(app.WidefieldImager);
            app.ImagePlot.Tag = 'ImagePlot';
            app.axes3 = uiaxes(app.WidefieldImager);
            app.axes3.Tag = 'axes3';
            app.ControlPanel = uipanel(app.WidefieldImager);
            app.ControlPanel.Title = 'Controls';
            app.ControlPanel.Tag = 'ControlPanel';
            app.BlueLight = uibutton(app.ControlPanel, 'state');
            app.BlueLight.ValueChangedFcn = createCallbackFcn(app, @BlueLight_Callback, true);
            app.BlueLight.Tag = 'BlueLight';
            app.BlueLight.Text = 'Light OFF';
            app.TakeSnapshot = uibutton(app.ControlPanel, 'push');
            app.TakeSnapshot.ButtonPushedFcn = createCallbackFcn(app, @TakeSnapshot_Callback, true);
            app.TakeSnapshot.Tag = 'TakeSnapshot';
            app.TakeSnapshot.Text = 'Take Snapshot';
            app.StartPreview = uibutton(app.ControlPanel, 'push');
            app.StartPreview.ButtonPushedFcn = createCallbackFcn(app, @StartPreview_Callback, true);
            app.StartPreview.Tag = 'StartPreview';
            app.StartPreview.Text = 'Start Preview';
            app.StopPreview = uibutton(app.ControlPanel, 'push');
            app.StopPreview.ButtonPushedFcn = createCallbackFcn(app, @StopPreview_Callback, true);
            app.StopPreview.Tag = 'StopPreview';
            app.StopPreview.Text = 'Stop Preview';
            app.SelectROI = uibutton(app.ControlPanel, 'push');
            app.SelectROI.ButtonPushedFcn = createCallbackFcn(app, @SelectROI_Callback, true);
            app.SelectROI.Tag = 'SelectROI';
            app.SelectROI.Text = 'Select ROI';
            app.ResetROI = uibutton(app.ControlPanel, 'push');
            app.ResetROI.ButtonPushedFcn = createCallbackFcn(app, @ResetROI_Callback, true);
            app.ResetROI.Tag = 'ResetROI';
            app.ResetROI.Text = 'Reset ROI';
            app.Tag_CurrentResolution = uilabel(app.ControlPanel);
            app.Tag_CurrentResolution.Tag = 'Tag_CurrentResolution';
            app.Tag_CurrentResolution.Text = 'Current Resolution';
            app.CurrentResolution = uieditfield(app.ControlPanel, 'text');
            app.CurrentResolution.ValueChangedFcn = createCallbackFcn(app, @CurrentResolution_Callback, true);
            app.CurrentResolution.Tag = 'CurrentResolution';
            app.CurrentResolution.Value = 'empty';
            app.lightMode = uidropdown(app.ControlPanel);
            app.lightMode.Items = {'Blue only', 'Violet only', 'Mixed light'};
            app.lightMode.ValueChangedFcn = createCallbackFcn(app, @lightMode_Callback, true);
            app.lightMode.Tag = 'lightMode';
            app.lightMode.Value = 'Blue only';
            app.popupmenu9 = uidropdown(app.ControlPanel);
            app.popupmenu9.Items = {'Computer trigger', 'Camera trigger', 'Manual trigger'};
            app.popupmenu9.Tag = 'popupmenu9';
            app.popupmenu9.Value = 'Computer trigger';
            app.text17 = uilabel(app.ControlPanel);
            app.text17.Tag = 'text17';
            app.text17.Text = 'Light Sync';
            app.text18 = uilabel(app.ControlPanel);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Light Mode';
            app.edit14 = uieditfield(app.ControlPanel, 'text');
            app.edit14.Tag = 'edit14';
            app.edit14.Value = '0dB';
            app.edit16 = uieditfield(app.ControlPanel, 'text');
            app.edit16.ValueChangedFcn = createCallbackFcn(app, @ExposureTime_Callback, true);
            app.edit16.Tag = 'edit16';
            app.edit16.Value = 'Exposure Time';
            app.text19 = uilabel(app.ControlPanel);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Gain (dB)';
            app.text21 = uilabel(app.ControlPanel);
            app.text21.Tag = 'text21';
            app.text21.Text = 'Exposure Time';
            app.ExperimentID = uipanel(app.WidefieldImager);
            app.ExperimentID.Title = 'Experiment ID';
            app.ExperimentID.Tag = 'ExperimentID';
            app.RecordingID = uieditfield(app.ExperimentID, 'text');
            app.RecordingID.Tag = 'RecordingID';
            app.RecordingID.Enable = 'off';
            app.RecordingID.Value = 'empty';
            app.Tag_AnimalID = uilabel(app.ExperimentID);
            app.Tag_AnimalID.Tag = 'Tag_AnimalID';
            app.Tag_AnimalID.Text = 'Animal ID';
            app.Tag_RecordingID = uilabel(app.ExperimentID);
            app.Tag_RecordingID.Tag = 'Tag_RecordingID';
            app.Tag_RecordingID.Text = 'Recording ID';
            app.AnimalID = uidropdown(app.ExperimentID);
            app.AnimalID.Items = {'empty'};
            app.AnimalID.ValueChangedFcn = createCallbackFcn(app, @AnimalID_Callback, true);
            app.AnimalID.Tag = 'AnimalID';
            app.AnimalID.Value = 'empty';
            app.ExperimentType = uidropdown(app.ExperimentID);
            app.ExperimentType.Items = {'empty'};
            app.ExperimentType.ValueChangedFcn = createCallbackFcn(app, @ExperimentType_Callback, true);
            app.ExperimentType.Tag = 'ExperimentType';
            app.ExperimentType.Value = 'empty';
            app.Tag_ExperimentType = uilabel(app.ExperimentID);
            app.Tag_ExperimentType.Tag = 'Tag_ExperimentType';
            app.Tag_ExperimentType.Text = 'ExperimentType';
            app.TrialNr = uieditfield(app.ExperimentID, 'text');
            app.TrialNr.Tag = 'TrialNr';
            app.TrialNr.Enable = 'off';
            app.TrialNr.Value = 'empty';
            app.Tag_TrialNr = uilabel(app.ExperimentID);
            app.Tag_TrialNr.Tag = 'Tag_TrialNr';
            app.Tag_TrialNr.Text = 'TrialNr';
            app.NewAnimal = uibutton(app.ExperimentID, 'push');
            app.NewAnimal.ButtonPushedFcn = createCallbackFcn(app, @NewAnimal_Callback, true);
            app.NewAnimal.Tag = 'NewAnimal';
            app.NewAnimal.Text = 'New Animal';
            app.NewExperiment = uibutton(app.ExperimentID, 'push');
            app.NewExperiment.ButtonPushedFcn = createCallbackFcn(app, @NewExperiment_Callback, true);
            app.NewExperiment.Tag = 'NewExperiment';
            app.NewExperiment.Text = 'New Experiment';
            app.RecordMode = uidropdown(app.ExperimentID);
            app.RecordMode.Items = {'Mapping', 'Behavior'};
            app.RecordMode.ValueChangedFcn = createCallbackFcn(app, @RecordMode_Callback, true);
            app.RecordMode.Tag = 'RecordMode';
            app.RecordMode.Value = 'Mapping';
            app.DataStorage = uipanel(app.WidefieldImager);
            app.DataStorage.Title = 'Data storage';
            app.DataStorage.Tag = 'DataStorage';
            app.DataPath = uilabel(app.DataStorage);
            app.DataPath.Tag = 'DataPath';
            app.DataPath.Enable = 'off';
            app.DataPath.Text = 'empty';
            app.Tag_PostStimFrames = uilabel(app.DataStorage);
            app.Tag_PostStimFrames.Tag = 'Tag_PostStimFrames';
            app.Tag_PostStimFrames.Text = 'PostStim (s)';
            app.Tag_BaselineFrames = uilabel(app.DataStorage);
            app.Tag_BaselineFrames.Tag = 'Tag_BaselineFrames';
            app.Tag_BaselineFrames.Text = 'Baseline (s)';
            app.ChangeDataPath = uibutton(app.DataStorage, 'push');
            app.ChangeDataPath.ButtonPushedFcn = createCallbackFcn(app, @ChangeDataPath_Callback, true);
            app.ChangeDataPath.Tag = 'ChangeDataPath';
            app.ChangeDataPath.Text = 'Change file path';
            app.Tag_WaitingTime = uilabel(app.DataStorage);
            app.Tag_WaitingTime.Tag = 'Tag_WaitingTime';
            app.Tag_WaitingTime.Text = 'Wait (s)';
            app.BaselineFrames = uieditfield(app.DataStorage, 'text');
            app.BaselineFrames.Tag = 'BaselineFrames';
            app.BaselineFrames.Value = '2';
            app.PostStimFrames = uieditfield(app.DataStorage, 'text');
            app.PostStimFrames.Tag = 'PostStimFrames';
            app.PostStimFrames.Value = '22';
            app.WaitingTime = uieditfield(app.DataStorage, 'text');
            app.WaitingTime.Tag = 'WaitingTime';
            app.WaitingTime.Value = '60';
            app.Tag_FrameRate = uilabel(app.DataStorage);
            app.Tag_FrameRate.Tag = 'Tag_FrameRate';
            app.Tag_FrameRate.Text = 'FrameRate (Hz)';
            app.FrameRate = uieditfield(app.DataStorage, 'text');
            app.FrameRate.ValueChangedFcn = createCallbackFcn(app, @FrameRate_Callback, true);
            app.FrameRate.Tag = 'FrameRate';
            app.FrameRate.Value = '60';
            app.Tag_sBinning = uilabel(app.DataStorage);
            app.Tag_sBinning.Tag = 'Tag_sBinning';
            app.Tag_sBinning.Text = 'Binning';
            app.sBinning = uidropdown(app.DataStorage);
            app.sBinning.Items = {'1', '2', '4'};
            app.sBinning.ValueChangedFcn = createCallbackFcn(app, @sBinning_Callback, true);
            app.sBinning.Tag = 'sBinning';
            app.sBinning.Value = '4';
            app.driveSelect = uidropdown(app.DataStorage);
            app.driveSelect.Items = {'c:'};
            app.driveSelect.ValueChangedFcn = createCallbackFcn(app, @driveSelect_Callback, true);
            app.driveSelect.Tag = 'driveSelect';
            app.driveSelect.Value = 'c:';
            app.text16 = uilabel(app.DataStorage);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Base drive:';
            app.StatusPanel = uipanel(app.WidefieldImager);
            app.StatusPanel.Title = 'Recording status';
            app.StatusPanel.Tag = 'StatusPanel';
            app.WaitForTrigger = uibutton(app.StatusPanel, 'state');
            app.WaitForTrigger.ValueChangedFcn = createCallbackFcn(app, @WaitForTrigger_Callback, true);
            app.WaitForTrigger.Tag = 'WaitForTrigger';
            app.WaitForTrigger.Text = 'Wait for trigger OFF';
            app.CurrentStatus = uibutton(app.StatusPanel, 'push');
            app.CurrentStatus.Tag = 'CurrentStatus';
            app.CurrentStatus.Enable = 'off';
            app.CurrentStatus.Text = 'Not ready';
            app.AcqusitionStatus = uibutton(app.StatusPanel, 'state');
            app.AcqusitionStatus.ValueChangedFcn = createCallbackFcn(app, @AcqusitionStatus_Callback, true);
            app.AcqusitionStatus.Tag = 'AcqusitionStatus';
            app.AcqusitionStatus.Enable = 'off';
            app.AcqusitionStatus.Text = 'Inactive';
            app.lockGUI = uibutton(app.StatusPanel, 'state');
            app.lockGUI.ValueChangedFcn = createCallbackFcn(app, @lockGUI_Callback, true);
            app.lockGUI.Tag = 'lockGUI';
            app.lockGUI.Text = 'Released';
            app.uipanel6 = uipanel(app.WidefieldImager);
            app.uipanel6.Title = 'Manual acquisition control (Software triggers)';
            app.uipanel6.Tag = 'uipanel6';
            app.TrialTrigger = uibutton(app.uipanel6, 'state');
            app.TrialTrigger.Tag = 'TrialTrigger';
            app.TrialTrigger.Enable = 'off';
            app.TrialTrigger.Text = 'Trial Trigger';
            app.StimTrigger = uibutton(app.uipanel6, 'state');
            app.StimTrigger.Tag = 'StimTrigger';
            app.StimTrigger.Enable = 'off';
            app.StimTrigger.Text = 'Stimulus Trigger';
            app.StopTrigger = uibutton(app.uipanel6, 'state');
            app.StopTrigger.Tag = 'StopTrigger';
            app.StopTrigger.Enable = 'off';
            app.StopTrigger.Text = 'Stop Trigger';
            app.triggerLock = uibutton(app.uipanel6, 'state');
            app.triggerLock.ValueChangedFcn = createCallbackFcn(app, @triggerLock_Callback, true);
            app.triggerLock.Tag = 'triggerLock';
            app.triggerLock.Text = 'Locked';
            app.triggerLock.Value = true;
            app.CalibrationMode = uibutton(app.WidefieldImager, 'state');
            app.CalibrationMode.ValueChangedFcn = createCallbackFcn(app, @CalibrationMode_Callback, true);
            app.CalibrationMode.Tag = 'CalibrationMode';
            app.CalibrationMode.Text = 'Calibration';
            app.slider4 = uislider(app.WidefieldImager);
            app.slider4.Limits = [0 1];
            app.slider4.MajorTicks = [];
            app.slider4.MinorTicks = [];
            app.slider4.Tag = 'slider4';
            % Show the figure after all components are created
            app.WidefieldImager.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WidefieldImager_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.WidefieldImager)

                % Execute the startup function
                runStartupFcn(app, @(app)WidefieldImager_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.WidefieldImager)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WidefieldImager)
        end
    end
end